classdef Trans_visualize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        INPUTPanel         matlab.ui.container.Panel
        INPUTEditField     matlab.ui.control.EditField
        Label              matlab.ui.control.Label
        Button             matlab.ui.control.Button
        DataTextAreaLabel  matlab.ui.control.Label
        DataTextArea       matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function coordinates = point(app)
%             if exist('transform_list','var')
%                 % do nothing
%             else
%                 [transform_list, transformations] = Transform.transform;
% %             disp(strlength(transform_list))
            
%             while 1
    %                coord = [];
            if length(coordinates) > 1
                coordinates = coordinates;
            else
                coordinates = [];
            end
            app.Label.Text = 'Enter the coordinates of the shape in the format (x,y) or D when done or cLear to start again:';
            coord_in = app.INPUTEditField.Value;
            if strlength(coord_in) == 0
                app.Label.Text = 'You have entered no coordinates, try again.';
                app.point
                %                     continue
            else
                if strcmpi(coord_in,'d')
                    if (length(coordinates(:, 1)) == 1) && (strlength(transform_list) == 1) && (strcmpi(transform_list(1), 't'))
                            % do nothing
                        return
%                         break
                    elseif length(coordinates(:, 1)) == 2
                            % do nothing
                        return
%                             break
                    elseif length(coordinates(:, 1)) > 2
                            % do nothing
                        return
%                             break
                    else
                        app.Label.Text = 'Cannot transform a point, Check transformation and points.';
                        app.point
    %                        Transform.check_coordinates
                    end
                elseif strcmpi(coord_in,'l')
                    app.point
                elseif strcmpi(coord_in,'s')
                    app.transform
                elseif length(str2num(coord_in)) < 2 || length(str2num(coord_in)) > 3
                    app.Label.Text = 'This program can only work on 2D and 3D objects, try again.';
                    app.point
                else
                    try
                        coord_ = str2num(coord_in);
                        if isempty(coord_)
                            app.Label.Text = 'You have entered a letter instead of numbers as coordinates';
                            app.point
                        end
                        coord_ = cat(1, coord_', 1);
                        coordinates = cat(1, coordinates, coord_');
                        app.point
                        
                    catch ME
                        switch ME.identifier
                            case 'MATLAB:catenate:dimensionMismatch'
                                app.Label.Text = 'The dimensions are not consistent, try again';
                                app.point
%                                 break
                            otherwise
                                app.Label.Text = ME;
                                app.point
%                                     continue
                        end
                        
%                            disp('An error occured with the coordinates, try again.')
                        
                    end
                end
            end
%             end
        disp('Coordinates are homogenous')
%             disp(coordinates)
%             end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if exist('transform_list','var')
                transform_list = transform_list;
            else
                transform_list = [];
            end
            keys = {'T', 'O', 'C', 'S', 'R'};
            values = {'Transform.translate', 'Transform.rotate', 'Transform.scale', 'Transform.shear', 'Transform.reflect'};
            transformations = containers.Map(keys, values);
%             while 1 
                app.Label.Text = 'Enter the transformation(s) you want to do: In the order you want to do them\nTranslate, rOtate, sCale, Shear, Reflect or Done when you are done or cLear to start again:';
                transformation = app.INPUTEditField.Value;
                if ismember(upper(transformation), keys)
                    transform_list = cat(1, transform_list, upper(transformation));
                    app.INPUTEditField.Value = '';
                    app.DataTextArea.Value = transform_list;
%                     app.Button.ButtonPushedFcn = app.ButtonPushed;
%                     ButtonPushed(app, event)
                elseif strcmpi(transformation, 'd')
                    if isempty(transform_list)
                        disp('You have entered no transformation, try again.')
                        ButtonPushed(app, event)
%                         continue
                    else
                        transform_list = transform_list';
                        app.Label.Text = 'Enter the coordinates of the shape in the format (x,y) or D when done or cLear to start again:';
                        coordinates = app.point;
                        app.DataTextArea.Value = coordinates;
                        app.DataTextArea.Value = transform_list;
%                         break
                    end
                elseif strcmpi(transformation, 'l')
                    ButtonPushed(app, event)
                else
                    app.Label.Text = 'You have entered an incorrect transformation, try again.';
                    ButtonPushed(app, event)
                end
%             end
            
%             disp(transform_list)
%             class(transform_list)
%             Transform.point
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Shape plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.INPUTPanel = uipanel(app.UIFigure);
            app.INPUTPanel.Title = 'INPUT';
            app.INPUTEditField = uieditfield(app.INPUTPanel, 'text');
            app.Label = uilabel(app.INPUTPanel);
            app.Label.Text = 'Enter the transformation(s) you want to do: In the order you want to do them\nTranslate, rOtate, sCale, Shear, Reflect or Done when you are done or cLear to start again:''';
            app.Button = uibutton(app.INPUTPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DataTextAreaLabel = uilabel(app.UIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.UIFigure);
            app.DataTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trans_visualize

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end